classdef analysis_Entropy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SampleTab                      matlab.ui.container.Tab
        MinimumDataLengthSpinner       matlab.ui.control.Spinner
        MinimumDataLengthSpinnerLabel  matlab.ui.control.Label
        ProcessButton                  matlab.ui.control.Button
        Label2                         matlab.ui.control.Label
        rEditField                     matlab.ui.control.EditField
        rEditFieldLabel                matlab.ui.control.Label
        mEditField                     matlab.ui.control.EditField
        mEditFieldLabel                matlab.ui.control.Label
        MultiscaleTab                  matlab.ui.container.Tab
        ProcessButton_2                matlab.ui.control.Button
        Version                        matlab.ui.control.Label
        SelectthesignalstoprocessListBox  matlab.ui.control.ListBox
        SelectthesignalstoprocessListBoxLabel  matlab.ui.control.Label
        Switch_2                       matlab.ui.control.Switch
        SaveResultsandExitButton       matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        Image                          matlab.ui.control.Image
        TimeLagandAMIAnalysisLabel     matlab.ui.control.Label
    end


    properties (Access = private)
        BAR_App   % Main app object
    end

    methods (Access = private)

        function printLog(app, code, newText)
            % printLog(app, code, newText)
            % inputs  - app, required mlapp object.
            %         - code, an error code used to identify the source of
            %           the error and organize the errors available.
            %         - newText, a string that may be inserted into the
            %                    message.
            % Remarks
            % - This method will print messages to the log of the BAR App.
            %   The error codes are unique to each message so they can be
            %   found and referenced easier.
            % - The method is public and is acessible outside of the BAR
            %   App. It is used in some of the example analysis and process
            %   scripts.
            % Future Work
            % - This log should be printed and saved to a file location
            %   each time the BAR App is run.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current text within the log.
            text = app.BAR_App.Log.Value;

            % Match the error code with the message and update the text for
            % the log.
            switch code
                case '001'
                    text{length(text) + 1, 1} = ['AMI-001 ' newText];
                case '002'
                    text{length(text) + 1, 1} = ['AMI-002 analysis complete'];
                case '003'
                    text{length(text) + 1, 1} = ['AMI-003 analysis failed on: ' newText];
                case '004'
                    text{length(text) + 1, 1} = ['AMI-004 ' newText];
                case '005'
                    text{length(text) + 1, 1} = ['AMI-005 existing analysis, more than 10% have failed'];
            end

            % Update the log.
            app.BAR_App.Log.Value = text;

            % Scroll to the bottom so the most recent message is visible.
            scroll(app.BAR_App.Log,'bottom')
            drawnow
        end

        function updatelist(app)
            % updatelist(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method updates the signal list within the app with the
            %   currently selected data type.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu


            % Get the current data from the app.
            data = getappdata(app.UITable, 'data');
            % Find which data type was selected.
            switch app.Switch_2.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end
            % Initialize the list with an 'all' option.
            sigsAll = {'all'};
            % Get the file names.
            files = fieldnames(data.(type));
            % Iterate through the file names.
            for i = 1:length(files)
                % Get the object names and remove the informational ones.
                objs = fieldnames(data.(type).(files{i}));
                objs(strcmp(objs, 'groups')) = [];
                objs(strcmp(objs, 'meta')) = [];
                % Iterate through the objects
                for j = 1:length(objs)
                    % Append the signals to the list.
                    sigsAll = [sigsAll; fieldnames(data.(type).(files{i}).(objs{j}).data)];
                end
            end
            % Set the unique signals to the list box.
            app.SelectthesignalstoprocessListBox.Items = unique(sigsAll);

        end

        function processData(app, entropy)
            % processData(app, entropy)
            % inputs  - app, required mlapp object.
            %         - entropy, a string of the type of Entropy analysis to run.
            % Remarks
            % - This method starts the analysis method. It pulls much of the inputs from the app interface. A different button is in each tab and each
            %   maps to this analysis method.
            % Future Work
            % - A couple other methods could be added as options.
            % Mar 2023 - Created by Ben Senderling, bsender@bu.edu

            % Get the data from the app.
            data = getappdata(app.UITable, 'data');

            % Empty the current table.
            app.UITable.Data = [];

            % Get the selected level of data to process.
            switch app.Switch_2.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            % Initialize an index for the array.
            ind = 1;

            % Initialize a variable to count the fails.
            fails = 0;

            % The module
            switch entropy
                % If the user selects time lags to be calculated.
                case 'sample'
                    mEntropy = split(app.mEditField.Value, ',');
                    ind2 = 1;
                    for i = 1:length(mEntropy)
                        if ~isnan(str2double(mEntropy{i})) && ~contains(mEntropy{i}, '-')
                            mEntropy2(ind2, 1) = str2double(mEntropy{i});
                            ind2 = ind2 + 1;
                        elseif strcmp(mEntropy{i}(end), '-')
                            temp = str2double(mEntropy{i}(1:strfind(mEntropy{i}, '-') - 1)):length(cols);
                            mEntropy2(ind2:ind2 + length(temp) - 1, 1) = temp;
                            ind2 = ind2 + length(temp);
                        elseif strcmp(mEntropy{i}(1), '-')
                            temp = 1:str2double(mEntropy{i}(strfind(mEntropy{i}, '-') + 1));
                            mEntropy2(ind2:ind2 + length(temp) - 1, 1) = temp;
                            ind2 = ind2 + length(temp);
                        elseif contains(mEntropy{i}, '-')
                            temp = str2double(mEntropy{i}(1:strfind(mEntropy{i}, '-') - 1)):str2double(mEntropy{i}(strfind(mEntropy{i}, '-') + 1:end));
                            mEntropy2(ind2:ind2 + length(temp) - 1, 1) = temp;
                            ind2 = ind2 + length(temp);
                        elseif length(strfind(mEntropy{i}, ':')) == 1
                            temp = str2double(mEntropy{i}(1:strfind(mEntropy{i}, '-') - 1)):str2double(mEntropy{i}(strfind(mEntropy{i}, '-') + 1:end));
                        end

                    end
                    mEntropy = sort(unique(round(mEntropy2,0)));

                    rEntropy = split(app.rEditField.Value, ',');
                    ind3 = 1;
                    for i = 1:length(rEntropy)
                        if ~isnan(str2double(rEntropy{i})) && ~contains(rEntropy{i}, '-')
                            rEntropy2(ind3, 1) = str2double(rEntropy{i});
                            ind3 = ind3 + 1;
                        elseif strcmp(rEntropy{i}(end), '-')
                            temp = str2double(rEntropy{i}(1:strfind(rEntropy{i}, '-') - 1)):length(cols);
                            rEntropy2(ind3:ind3 + length(temp) - 1, 1) = temp;
                            ind3 = ind3 + length(temp);
                        elseif strcmp(rEntropy{i}(1), '-')
                            temp = 1:str2double(rEntropy{i}(strfind(rEntropy{i}, '-') + 1));
                            rEntropy2(ind3:ind3 + length(temp) - 1, 1) = temp;
                            ind3 = ind3 + length(temp);
                        elseif contains(rEntropy{i}, '-')
                            temp = str2double(rEntropy{i}(1:strfind(rEntropy{i}, '-') - 1)):str2double(rEntropy{i}(strfind(rEntropy{i}, '-') + 1:end));
                            rEntropy2(ind3:ind3 + length(temp) - 1, 1) = temp;
                            ind3 = ind3 + length(temp);
                        end

                    end
                    rEntropy = sort(rEntropy2);

                    % Get the field names of all the files that were loaded.
                    files = fieldnames(data.(type));

                    for i = 1:length(files)
                        % Get the fieldnames for all the signals that were
                        % contained within that file. This could be different for
                        % each file so it is within the loop.
                        objs = fieldnames(data.(type).(files{i}));
                        objs(strcmp(objs, 'groups')) = [];
                        objs(strcmp(objs, 'meta')) = [];
                        % Iterate through the objects.
                        for j = 1:length(objs)
                            % Get the user selected signals.
                            sigs = fieldnames(data.(type).(files{i}).(objs{j}).data);
                            % Iterate through the signals.
                            for k = 1:length(sigs)
                                % Check if 'all' or nothing was selected or the current signal matches the selection.
                                if any(contains(sigs, 'all')) || isempty(sigs) || any(strcmp(app.SelectthesignalstoprocessListBox.Value, sigs{k}))
                                    % Get the number of dimensions to use.
                                    m = size(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 2);

                                    % Iterate through the dimensions.
                                    for ii = 1:m
                                        % Come up with a tag to reference the
                                        % dimensions.
                                        if ii < 10
                                            mString = ['00' num2str(ii)];
                                        elseif m < 100
                                            mString = ['0' num2str(ii)];
                                        elseif m < 1000
                                            mString = num2str(ii);
                                        end

                                        ind_SE = 1;

                                        for jj = 1:length(mEntropy)

                                            for kk = 1:length(rEntropy)

                                                if size(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 1) > app.MinimumDataLengthSpinner.Value

                                                    try
                                                        SE = Ent_Samp(data.(type).(files{i}).(objs{j}).data.(sigs{k})(:,ii), mEntropy(jj), rEntropy(kk));
                                                        % All of the results will be saved to the analysis type.
                                                        data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE(ind_SE, 1) = SE;
                                                        data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE_m(ind_SE, 1) = mEntropy(jj);
                                                        data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE_r(ind_SE, 1) = rEntropy(kk);
                                                        data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE_N(ind_SE, 1) = size(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 1);
                                                        data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE_Mean(ind_SE, 1) = mean(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 1);
                                                        data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE_SD(ind_SE, 1) = std(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 1);

                                                        % Save results to the table structure.
                                                        dataTable.File{ind, 1} = files{i};
                                                        dataTable.Object{ind, 1} = objs{j};
                                                        dataTable.Signal{ind, 1} = sigs{k};
                                                        dataTable.Dimension{ind, 1} = ii;

                                                        dataTable.SampleEntropy(ind, 1) = SE;
                                                        dataTable.m(ind, 1) = mEntropy(jj);
                                                        dataTable.r(ind, 1) = rEntropy(kk);
                                                        dataTable.N(ind, 1) = data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE_N(ind_SE, 1);
                                                        dataTable.Mean(ind, 1) = data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE_Mean(ind_SE, 1);
                                                        dataTable.STD(ind, 1) = data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE_SD(ind_SE, 1);

                                                    catch ME
                                                        % If there is an error set all the same reults to NaNs.
                                                        data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE(ind_SE, 1) = NaN;
                                                        data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE_m(ind_SE, 1) = mEntropy(jj);
                                                        data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE_r(ind_SE, 1) = rEntropy(kk);
                                                        data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE_N(ind_SE, 1) = size(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 1);
                                                        data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE_Mean(ind_SE, 1) = mean(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 1);
                                                        data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE_SD(ind_SE, 1) = std(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 1);

                                                        dataTable.File{ind, 1} = files{i};
                                                        dataTable.Object{ind, 1} = objs{j};
                                                        dataTable.Signal{ind, 1} = sigs{k};
                                                        dataTable.Dimension{ind, 1} = ii;

                                                        dataTable.SampleEntropy(ind, 1) = NaN;
                                                        dataTable.m(ind, 1) = mEntropy(jj);
                                                        dataTable.r(ind, 1) = rEntropy(kk);
                                                        dataTable.N(ind, 1) = data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE_N(ind_SE, 1);
                                                        dataTable.Mean(ind, 1) = mean(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 1);
                                                        dataTable.STD(ind, 1) = std(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 1);

                                                        % Print a message saying which data failed.
                                                        printLog(app, '003', [files{i} ' ' objs{j} ' ' sigs{k}])

                                                        % Get MATLAB's last error message and display it.
                                                        printLog(app, '004', ME.message)

                                                        fails = fails + 1;
                                                        if fails/length(files) > 0.1
                                                            printLog(app, '005')
                                                            return
                                                        end

                                                    end
                                                    % Increase the index for the table structure.
                                                    ind = ind + 1;

                                                else
                                                    data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE(ind_SE, 1) = NaN;
                                                    data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE_m(ind_SE, 1) = mEntropy(jj);
                                                    data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE_r(ind_SE, 1) = rEntropy(kk);
                                                    data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE_N(ind_SE, 1) = size(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 1);
                                                    data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE_Mean(ind_SE, 1) = mean(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 1);
                                                    data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE_SD(ind_SE, 1) = std(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 1);

                                                    dataTable.File{ind, 1} = files{i};
                                                    dataTable.Object{ind, 1} = objs{j};
                                                    dataTable.Signal{ind, 1} = sigs{k};
                                                    dataTable.Dimension{ind, 1} = ii;

                                                    dataTable.SampleEntropy(ind, 1) = NaN;
                                                    dataTable.m(ind, 1) = mEntropy(jj);
                                                    dataTable.r(ind, 1) = rEntropy(kk);
                                                    dataTable.N(ind, 1) = data.res.Entropy.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).SE_N(ind_SE, 1);
                                                    dataTable.Mean(ind, 1) = mean(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 1);
                                                    dataTable.STD(ind, 1) = std(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 1);

                                                    % Print a message saying which data
                                                    % failed.
                                                    printLog(app, '003', [files{i} ' ' objs{j} ' ' sigs{k}])

                                                    % Get MATLAB's last error message and display it.
                                                    printLog(app, '004', 'data is less than the minimum length')
                                                end

                                                ind_SE = ind_SE + 1;
                                            end
                                        end

                                    end

                                end
                            end
                        end
                        % Every 10 files print an update message.
                        if rem(i, 10) == 0
                            printLog(app, '001', ['finished ' num2str(i) ' of ' num2str(height(files)) ' files'])
                            % Sent the table structure to the module's table and set the
                            % column names to the fieldnames.
                            app.UITable.Data = struct2table(dataTable);
                            app.UITable.ColumnName = fieldnames(dataTable);
                        end
                    end

                case 'multiscale'

            end
            % Print a message complete message to the log.
            printLog(app, '002')
            % Save the data to the module.
            setappdata(app.UITable, 'data', data)
            % Sent the table structure to the module's table and set the
            % column names to the fieldnames.
            app.UITable.Data = struct2table(dataTable);
            app.UITable.ColumnName = fieldnames(dataTable);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data, analysis)
            % StartupFcn(app, mainapp, data, analysis)
            % inputs  - app, required mlapp object.
            %         - mlapp, BAR App objects.
            %         - data, BAR App data structure.
            %         - analysis, string of the analysis name.
            % Remarks
            % - This is the startup method that initiallized the app.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;
            % Save the data into the module.
            setappdata(app.UITable, 'data', data)
            % Initialize the signal list in the app.
            updatelist(app)
            % Update the version number label with the label from the BAR App.
            app.Version.Text = app.BAR_App.Version.Text;
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)

            processData(app, 'sample')

        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)

            % Delete the dialog box
            delete(app)

        end

        % Callback function
        function SwitchValueChanged(app, event)
            % SwitchValueChanged(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app
            %                  from the button press.
            % Remarks
            % - This method controls the ability to use the time lag
            %   spinner. If the user selects 'AMI' the spinner is disabled.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current value of the spinner.
            value = app.Switch.Value;
            % If 'TIme Lag' is selected enable it and if 'AMI' is selected
            % disable it.
            switch value
                case 'Time Lag'
                    app.MaximumLagSpinner.Enable = 'on';
                case 'AMI'
                    app.MaximumLagSpinner.Enable = 'off';
            end

        end

        % Button pushed function: SaveResultsandExitButton
        function SaveResultsandExitButtonPushed(app, event)
            % SaveResultsandExitButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app
            %                  from the button press.
            % Remarks
            % - This method will send the data back to the BAR App and save
            %   it.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current data from the module.
            data = getappdata(app.UITable, 'data');
            % The analysis name that will be used to save the data.
            analysis = 'TimeLag';
            % Save the data using a BAR_App method.
            analysisComplete(app.BAR_App, data, analysis, 0)
            % Close the app.
            delete(app)
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            updatelist(app)
        end

        % Button pushed function: ProcessButton_2
        function ProcessButton_2Pushed(app, event)

            processData(app, 'multiscale')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.TimeLagandAMIAnalysisLabel = uilabel(app.UIFigure);
            app.TimeLagandAMIAnalysisLabel.Text = 'Time Lag and AMI Analysis';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SaveResultsandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsandExitButtonPushed, true);
            app.SaveResultsandExitButton.Text = 'Save Results and Exit';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'Raw', 'Processed'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Raw';
            app.SelectthesignalstoprocessListBoxLabel = uilabel(app.UIFigure);
            app.SelectthesignalstoprocessListBoxLabel.Text = 'Select the signals to process';
            app.SelectthesignalstoprocessListBox = uilistbox(app.UIFigure);
            app.SelectthesignalstoprocessListBox.Items = {};
            app.SelectthesignalstoprocessListBox.Multiselect = 'on';
            app.SelectthesignalstoprocessListBox.Value = {};
            app.Version = uilabel(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SampleTab = uitab(app.TabGroup);
            app.SampleTab.Title = 'Sample';
            app.mEditFieldLabel = uilabel(app.SampleTab);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.SampleTab, 'text');
            app.rEditFieldLabel = uilabel(app.SampleTab);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.SampleTab, 'text');
            app.Label2 = uilabel(app.SampleTab);
            app.Label2.Text = 'Enter single numbers for m and r. Or enter multiple numbers separated by commas (Ex: 2,3,4). With multiple values either m or r can have one value or both have the same number of values.';
            app.ProcessButton = uibutton(app.SampleTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.MinimumDataLengthSpinnerLabel = uilabel(app.SampleTab);
            app.MinimumDataLengthSpinnerLabel.Text = 'Minimum Data Length';
            app.MinimumDataLengthSpinner = uispinner(app.SampleTab);
            app.MultiscaleTab = uitab(app.TabGroup);
            app.MultiscaleTab.Title = 'Multiscale';
            app.ProcessButton_2 = uibutton(app.MultiscaleTab, 'push');
            app.ProcessButton_2.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_2Pushed, true);
            app.ProcessButton_2.Text = 'Process';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysis_Entropy(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end