classdef analysis_RQA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Switch                          matlab.ui.control.Switch
        TimelagselectionSwitch          matlab.ui.control.Switch
        TimelagselectionSwitchLabel     matlab.ui.control.Label
        SaveperiodicallySwitch          matlab.ui.control.Switch
        SaveperiodicallySwitchLabel     matlab.ui.control.Label
        SetValueSpinner                 matlab.ui.control.Spinner
        SetValueSpinnerLabel            matlab.ui.control.Label
        EmbeddingDimensionDropDown      matlab.ui.control.DropDown
        EmbeddingDimensionDropDownLabel  matlab.ui.control.Label
        TargetValueSpinner              matlab.ui.control.Spinner
        TargetValueSpinnerLabel         matlab.ui.control.Label
        TargetParameterSwitch           matlab.ui.control.Switch
        TargetParameterSwitchLabel      matlab.ui.control.Label
        NormalizationTypeDropDown       matlab.ui.control.DropDown
        NormalizationTypeDropDownLabel  matlab.ui.control.Label
        ZScoreSwitch                    matlab.ui.control.Switch
        ZScoreSwitchLabel               matlab.ui.control.Label
        RQAMethodDropDown               matlab.ui.control.DropDown
        RQAMethodDropDownLabel          matlab.ui.control.Label
        SaveResultsandExitButton        matlab.ui.control.Button
        Log                             matlab.ui.control.TextArea
        UITable                         matlab.ui.control.Table
        Image                           matlab.ui.control.Image
        RecurranceQuantificationAnalysisLabel  matlab.ui.control.Label
        ProcessButton                   matlab.ui.control.Button
    end


    properties (Access = private) 
        BAR_App   % Main app object
    end
    
    methods (Access = private)
        
        function printLog(app, code, newText)
            text = app.Log.Value;
            switch code
                case '001'
                    text{length(text) + 1,1} = [newText{1}];
                case '002'
                    text{length(text) + 1,1} = 'analysis complete';
            end
            app.Log.Value = text;
            scroll(app.Log,'bottom')
            drawnow
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data, analysis)
            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;
            app.RecurranceQuantificationAnalysisLabel.Text = analysis;
            
            setappdata(app.UITable, 'data', data)
            
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            
            data = getappdata(app.UITable, 'data');

            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'processed';
            end

            typeRQA = app.RQAMethodDropDown.Value;
            switch app.ZScoreSwitch.Value
                case 'Yes'
                    zScore = 1;
                case 'No'
                    zScore = 0;
            end
            switch app.NormalizationTypeDropDown.Value
                case 'Euclidean'
                    norm = 'euc';
                case 'Maximum Distance'
                    norm = 'max';
                case 'Minimum Distance'
                    norm = 'min';
                case 'No Normalization'
                    norm = 'non';
            end
            setPara = app.TargetParameterSwitch.Value;
            setValue = app.TargetValueSpinner.Value;
            plotOption = 0;

            dims = {};
            ind = 1;
            files = fieldnames(data.(type));
            for i = 1:length(files)
                objs = fieldnames(data.(type).(files{i}));
                for j = 1:length(objs)
                    sigs = fieldnames(data.(type).(files{i}).(objs{j}).data);
                    for k = 1:length(sigs)
                        for ii = 1:size(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 2)
                            dims{ii}(ind,1) = data.res.FNN.(files{i}).(objs{j}).(sigs{k}).dim{ii};
                        end
                        ind = ind + 1;
                    end
                end
            end
            switch app.EmbeddingDimensionDropDown.Value
                case 'Mode'
                    for i = 1:length(dims)
                        dims{i} = mode(dims{i});
                    end
                case 'Median'
                    for i = 1:length(dims)
                        dims{i} = median(dims{i});
                    end
                case 'Mean'
                    for i = 1:length(dims)
                        dims{i} = mean(dims{i}, 'omitnan');
                    end
                case 'Set Value'
                    for i = 1:length(dims)
                        dims{i} = app.SetValueSpinner.Value;
                    end
            end

            % Get the field names of all the files that were loaded.
            files = fieldnames(data.(type));
            ind2 = 1;
            for i = 1:length(files)

                if isfield(data.res, 'RQA') && any(strcmp(fieldnames(data.res.RQA),[files{i} '_' objs{j}]))
                    continue
                end

                % Get the fieldnames for all the signals that were
                % contained within that file. This could be different for
                % each file so it is within the loop.
                objs = fieldnames(data.(type).(files{i}));
                for j = 1:length(objs)
                    
                    sigs = fieldnames(data.(type).(files{i}).(objs{j}).data);

                    for k = 1:length(sigs)

                        for ii = 1:size(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 2)

                            switch app.TimelagselectionSwitch.Value
                                case 'First Minimum'
                                    tau = data.res.TimeLag.(files{i}).(objs{j}).(sigs{k}).tau1st{ii}(1,1);
                                case '1 Fifth Value'
                                    tau = data.res.TimeLag.(files{i}).(objs{j}).(sigs{k}).tau15th{ii}(end,1);
                            end

                            if strcmp(app.EmbeddingDimensionDropDown.Value, 'Unique')
                                dims{ii} = data.res.FNN.(files{i}).(objs{j}).(sigs{k}).dim{ii};
                            end

                            if isnan(tau)
                                data.res.RQA.(files{i}).(objs{j}).(sigs{k}).rp{ii} = NaN;
                                data.res.RQA.(files{i}).(objs{j}).(sigs{k}).results{ii} = NaN;
                            else

                                tic
                                [rp, results] = RQA(data.(type).(files{i}).(objs{j}).data.(sigs{k})(:, ii), typeRQA, tau, dims{ii}, zScore, norm, setPara, setValue, plotOption);
                                t = toc;
                                data.res.RQA.(files{i}).(objs{j}).(sigs{k}).rp{ii} = rp;
                                data.res.RQA.(files{i}).(objs{j}).(sigs{k}).results{ii} = results;

                            end

                            ind2 = ind2 + 1;

                        end
                    end

                    printLog(app, '001', {['complete: files ' num2str(i) '/' num2str(length(files)) ', objects ' num2str(j) '/' num2str(length(objs))]})

                    if strcmp(app.SaveperiodicallySwitch.Value, 'Yes') && rem(ind2,10) == 0
                        analysisComplete(app.BAR_App, data, 'RQA')
                        exportResults(app.BAR_App, data, {'RQA'})
                    end

                end
            end

            printLog(app, '002')

            setappdata(app.UITable, 'data', data)

        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Callback function
        function SwitchValueChanged(app, event)
            value = app.CreateRQAplotSwitch.Value;
            
            switch value
                case 'Time Lag'
                    app.MaximumDimensionSpinner.Enable = 'on';
                case 'AMI'
                    app.MaximumDimensionSpinner.Enable = 'off';
            end

        end

        % Button pushed function: SaveResultsandExitButton
        function SaveResultsandExitButtonPushed(app, event)
            data = getappdata(app.UITable, 'data');
            analysis = app.RecurranceQuantificationAnalysisLabel.Text;
            
            analysisComplete(app.BAR_App, data, analysis)

            delete(app)
        end

        % Value changed function: TargetParameterSwitch
        function TargetParameterSwitchValueChanged(app, event)
            value = app.TargetParameterSwitch.Value;
            switch value
                case 'Recurrence'
                    app.TargetValueSpinner.Step = 0.1;
                    app.TargetValueSpinner.Limits = [0.1, 100];
                    app.TargetValueSpinner.Value = 2.5;
                case 'Radius'
                    app.TargetValueSpinner.Step = 0.001;
                    app.TargetValueSpinner.Limits = [0.001, inf];
                    app.TargetValueSpinner.Value = 1;
            end
        end

        % Value changed function: EmbeddingDimensionDropDown
        function EmbeddingDimensionDropDownValueChanged(app, event)
            value = app.EmbeddingDimensionDropDown.Value;
            switch value
                case 'Set Value'
                    app.SetValueSpinner.Enable = 1;
                    app.SetValueSpinnerLabel.Enable = 1;
                otherwise
                    app.SetValueSpinner.Enable = 0;
                    app.SetValueSpinnerLabel.Enable = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.RecurranceQuantificationAnalysisLabel = uilabel(app.UIFigure);
            app.RecurranceQuantificationAnalysisLabel.Text = 'Recurrance Quantification Analysis';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Log = uitextarea(app.UIFigure);
            app.Log.Editable = 'off';
            app.SaveResultsandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsandExitButtonPushed, true);
            app.SaveResultsandExitButton.Text = 'Save Results and Exit';
            app.RQAMethodDropDownLabel = uilabel(app.UIFigure);
            app.RQAMethodDropDownLabel.Text = 'RQA Method';
            app.RQAMethodDropDown = uidropdown(app.UIFigure);
            app.RQAMethodDropDown.Items = {'RQA', 'cRQA', 'jRQA', 'mdRQA'};
            app.RQAMethodDropDown.Value = 'RQA';
            app.ZScoreSwitchLabel = uilabel(app.UIFigure);
            app.ZScoreSwitchLabel.Text = 'Z Score?';
            app.ZScoreSwitch = uiswitch(app.UIFigure, 'slider');
            app.ZScoreSwitch.Items = {'Yes', 'No'};
            app.ZScoreSwitch.Value = 'Yes';
            app.NormalizationTypeDropDownLabel = uilabel(app.UIFigure);
            app.NormalizationTypeDropDownLabel.Text = 'Normalization Type';
            app.NormalizationTypeDropDown = uidropdown(app.UIFigure);
            app.NormalizationTypeDropDown.Items = {'Euclidean', 'Maximum Distance', 'Minimum Distance', 'No Normalization'};
            app.NormalizationTypeDropDown.Value = 'No Normalization';
            app.TargetParameterSwitchLabel = uilabel(app.UIFigure);
            app.TargetParameterSwitchLabel.Text = 'Target Parameter';
            app.TargetParameterSwitch = uiswitch(app.UIFigure, 'slider');
            app.TargetParameterSwitch.Items = {'Recurrence', 'Radius'};
            app.TargetParameterSwitch.ValueChangedFcn = createCallbackFcn(app, @TargetParameterSwitchValueChanged, true);
            app.TargetParameterSwitch.Value = 'Recurrence';
            app.TargetValueSpinnerLabel = uilabel(app.UIFigure);
            app.TargetValueSpinnerLabel.Text = 'Target Value';
            app.TargetValueSpinner = uispinner(app.UIFigure);
            app.TargetValueSpinner.Step = 0.1;
            app.TargetValueSpinner.Limits = [0.1 100];
            app.TargetValueSpinner.Value = 2.5;
            app.EmbeddingDimensionDropDownLabel = uilabel(app.UIFigure);
            app.EmbeddingDimensionDropDownLabel.Text = 'Embedding Dimension';
            app.EmbeddingDimensionDropDown = uidropdown(app.UIFigure);
            app.EmbeddingDimensionDropDown.Items = {'Unique', 'Mode', 'Mean', 'Median', 'Set Value'};
            app.EmbeddingDimensionDropDown.ValueChangedFcn = createCallbackFcn(app, @EmbeddingDimensionDropDownValueChanged, true);
            app.EmbeddingDimensionDropDown.Value = 'Unique';
            app.SetValueSpinnerLabel = uilabel(app.UIFigure);
            app.SetValueSpinnerLabel.Enable = 'off';
            app.SetValueSpinnerLabel.Text = 'Set Value';
            app.SetValueSpinner = uispinner(app.UIFigure);
            app.SetValueSpinner.Limits = [1 Inf];
            app.SetValueSpinner.RoundFractionalValues = 'on';
            app.SetValueSpinner.Enable = 'off';
            app.SetValueSpinner.Value = 1;
            app.SaveperiodicallySwitchLabel = uilabel(app.UIFigure);
            app.SaveperiodicallySwitchLabel.Text = 'Save periodically?';
            app.SaveperiodicallySwitch = uiswitch(app.UIFigure, 'slider');
            app.SaveperiodicallySwitch.Items = {'Yes', 'No'};
            app.SaveperiodicallySwitch.Value = 'Yes';
            app.TimelagselectionSwitchLabel = uilabel(app.UIFigure);
            app.TimelagselectionSwitchLabel.Text = 'Time lag selection?';
            app.TimelagselectionSwitch = uiswitch(app.UIFigure, 'slider');
            app.TimelagselectionSwitch.Items = {'First Minimum', '1 Fifth Value'};
            app.TimelagselectionSwitch.Value = 'First Minimum';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Raw', 'Processed'};
            app.Switch.Value = 'Raw';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysis_RQA(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end