classdef analysis_TimeLag < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SaveResultsandExitButton  matlab.ui.control.Button
        Log                       matlab.ui.control.TextArea
        UITable                   matlab.ui.control.Table
        Switch                    matlab.ui.control.Switch
        MaximumLagSpinner         matlab.ui.control.Spinner
        MaximumLagSpinnerLabel    matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        Label                     matlab.ui.control.Label
        ProcessButton             matlab.ui.control.Button
    end


    properties (Access = private) 
        BAR_App   % Main app object
    end
    
    methods (Access = private)
        
        function printLog(app, code, newText)
            text = app.Log.Value;
            switch code
                case '001'
                    text{length(text) + 1,1} = [newText{1}];
                case '002'
                    text{length(text) + 1,1} = 'analysis complete';
            end
            app.Log.Value = text;
            scroll(app.Log,'bottom')
            drawnow
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data, analysis)
            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;
            app.Label.Text = analysis;
            
            setappdata(app.UITable, 'data', data)
            
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            
            data = getappdata(app.UITable, 'data');

            switch app.Switch.Value
                case 'Time Lag'
                    lag = app.MaximumLagSpinner.Value;
                case 'AMI'
            end
            
            % Get the field names of all the files that were loaded.
            files = fieldnames(data.raw);
            for i = 1:length(files)
                % Get the fieldnames for all the signals that were
                % contained within that file. This could be different for
                % each file so it is within the loop.
                signals = fieldnames(data.raw.(files{i}));
                for j = 1:length(signals)
                    [tau,ami] = AMI_Stergiou(data.raw.(files{i}).(signals{j}).data(1:10:end), lag);
                    data.res.TimeLag.([files{i} '_' signals{j}]).tau = tau;
                    data.res.TimeLag.([files{i} '_' signals{j}]).ami = ami;

                    printLog(app, '001', {['complete: file: ' files{i} 'signal ' num2str(j) '/' num2str(length(signals))]})

                end
            end

            printLog(app, '002')

            setappdata(app.UITable, 'data', data)

        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            switch value
                case 'Time Lag'
                    app.MaximumLagSpinner.Enable = 'on';
                case 'AMI'
                    app.MaximumLagSpinner.Enable = 'off';
            end

        end

        % Button pushed function: SaveResultsandExitButton
        function SaveResultsandExitButtonPushed(app, event)

            data = getappdata(app.UITable, 'data');
            analysis = app.Label.Text;

            analysisComplete(app.BAR_App, data, analysis)

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.Label = uilabel(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.MaximumLagSpinnerLabel = uilabel(app.UIFigure);
            app.MaximumLagSpinnerLabel.Text = 'Maximum Lag';
            app.MaximumLagSpinner = uispinner(app.UIFigure);
            app.MaximumLagSpinner.Limits = [1 Inf];
            app.MaximumLagSpinner.RoundFractionalValues = 'on';
            app.MaximumLagSpinner.Value = 1;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Time Lag', 'AMI'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Time Lag';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Log = uitextarea(app.UIFigure);
            app.Log.Editable = 'off';
            app.SaveResultsandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsandExitButtonPushed, true);
            app.SaveResultsandExitButton.Text = 'Save Results and Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysis_TimeLag(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end