classdef process_Groupings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SelectthegroupwithdatestoconvertSpinner  matlab.ui.control.Spinner
        SelectthegroupwithdatestoconvertSpinnerLabel  matlab.ui.control.Label
        SelectthegroupdatesbelongtoSpinner  matlab.ui.control.Spinner
        SelectthegroupdatesbelongtoSpinnerLabel  matlab.ui.control.Label
        RemoveDuplicateswithinColumnsButton  matlab.ui.control.Button
        RemoveDuplicateswithinRowsButton  matlab.ui.control.Button
        IdentifyGroupsinObjectsButton  matlab.ui.control.Button
        v10Label                       matlab.ui.control.Label
        SaveResultsandExitButton       matlab.ui.control.Button
        ConvertDatesButton             matlab.ui.control.Button
        SelectdatetagformatDropDown    matlab.ui.control.DropDown
        SelectdatetagformatDropDownLabel  matlab.ui.control.Label
        Log                            matlab.ui.control.TextArea
        UITable                        matlab.ui.control.Table
        Image                          matlab.ui.control.Image
        GroupingsLabel                 matlab.ui.control.Label
    end


    properties (Access = private) 
        BAR_App   % Main app object
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function printLog(app, code, newText)
            text = app.Log.Value;
            switch code
                case '001'
                    text{length(text) + 1,1} = ['001 removing duplicates within columns'];
                case '002'
                    text{length(text) + 1,1} = ['002 duplicates removed from within columns'];
                case '003'
                    text{length(text) + 1,1} = ['003 removing duplicates within rows'];
                case '004'
                    text{length(text) + 1,1} = ['004 duplicates removed from within rows'];
                case '005'
                    text{length(text) + 1,1} = ['005 identifying object groups'];
                case '006'
                    text{length(text) + 1,1} = ['006 identifying object groups complete'];
                case '007'
                    text{length(text) + 1,1} = ['007 updating table'];
                case '008'
                    text{length(text) + 1,1} = ['008 updating table complete'];
            end
            app.Log.Value = text;
            scroll(app.Log,'bottom')
            drawnow
        end
        
        
        function updateTable(app, data)

            printLog(app, '007')
            
            groups = struct;

            files = fieldnames(data.raw);
            ind = 1;
            for i = 1:length(files)
                obj = fieldnames(data.raw.(files{i}));
                obj(strcmp(obj, 'meta')) = [];
                obj(strcmp(obj, 'groups')) = [];
                for k = 1:length(obj)
                    g = 1;
                    groups.(['group' num2str(g)]){ind, 1} = files{i};
                    g = g + 1;
                    for j = 1:length(data.raw.(files{i}).groups)
                        groups.(['group' num2str(g)]){ind, 1} = data.raw.(files{i}).groups{j};
                        g = g + 1;
                    end
                    groups.(['group' num2str(g)]){ind, 1} = obj{k};
                    g = g + 1;
                    if isfield(data.raw.(files{i}).(obj{k}), 'groups')
                        for ii = 1:length(data.raw.(files{i}).(obj{k}).groups)
                            groups.(['group' num2str(g)]){ind, 1} = data.raw.(files{i}).(obj{k}).groups{ii};
                            g = g + 1;
                        end
                        
                    else
                        groups.(['group' num2str(g)]){ind, 1} = obj{k};
                    end
                    ind = ind + 1;
                end
            end

            N = [];
            groupNames = fieldnames(groups);
            for i = 1:length(groupNames)
                N(i) = length(groups.(groupNames{i}));
            end
            for i = 1:length(groupNames)
                if length(groups.(groupNames{i})) < max(N)
                    groups.(groupNames{i})(N(i) + 1:max(N)) = {'NA'};
                end
            end
            
            app.UITable.Data = struct2table(groups);
            app.UITable.ColumnName = fieldnames(groups);

            printLog(app, '008')

        end
    end
    
    methods (Access = public)
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data)
            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;
            
            setappdata(app.UITable, 'data', data)

            updateTable(app, data)

        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Button pushed function: SaveResultsandExitButton
        function SaveResultsandExitSaveResultsandExitButtonPushed(app, event)
            data = getappdata(app.UITable, 'data');
            analysis = app.GroupingsLabel.Text;
            
            analysisComplete(app.BAR_App, data, analysis)

            delete(app)
        end

        % Callback function
        function findDelimiter(app, event)
            
            data = getappdata(app.UITable, 'data');

            n = [];
            files = fieldnames(data.raw);
            for i = 1:length(files)
                objs = fieldnames(data.raw.(files{i}));
                groups = split(objs, '_');
                j = 1;
                while j <= size(groups, 2)
                    if numel(unique(groups(:,j))) == length(groups) || numel(unique(groups(:,j))) == 1
                        groups(:,j) = [];
                    else
                        n = [n;numel(unique(groups(:,j)))];
                        j = j + 1;
                    end
                end
                for j = 1:size(groups, 2)
                    groupsTable.(['g' num2str(j)]) = [unique(groups(:,j)); num2cell(nan(max(n) - n(j), 1))];
                end
                for j = 1:length(objs)
                    for k = 1:size(groups, 2)
                        data.raw.(files{i}).(objs{j}).groups.(['g' num2str(k)]) = groups{j,k};
                    end
                end
            end
            setappdata(app.UITable, 'data', data)
            app.UITable.Data = struct2table(groupsTable);
            app.UITable.ColumnName = fieldnames(groupsTable);

        end

        % Callback function
        function findDates(app, event)
            value = app.AretheredatetagsinthenamesthatrepresentspecificvisitsSwitch.Value;

            if strcmp(value, 'No')
                return
            end

            app.SelectdatetagformatDropDown.Enable = 1;

            app.SelectsubjectgroupDropDown.Enable = 1;
            groupTable = app.UITable.Data;
            app.SelectsubjectgroupDropDown.Items = groupTable.Properties.VariableNames;

            app.ConvertDatesButton.Enable = 1;
            
        end

        % Button pushed function: ConvertDatesButton
        function ConvertDatesButtonPushed(app, event)
            
            groups = app.UITable.Data;
            data = getappdata(app.UITable, 'data');

            dateFormat = app.SelectdatetagformatDropDown.Value;
            ind_parent = app.SelectthegroupdatesbelongtoSpinner.Value;
            ind_date = app.SelectthegroupwithdatestoconvertSpinner.Value;

            parent = table2cell(unique(groups(:, ind_parent)));
            files = fieldnames(data.raw);
            for i = 1:length(parent)
                dates = unique(table2cell(groups(find(strcmp(groups.(ind_parent), parent{i})), ind_date)));
                dates = sort(dates);
                for j = 1:length(files)
                    if any(strcmp(data.raw.(files{j}).groups, parent{i}))
                        n = find(strcmp(dates, data.raw.(files{j}).groups{ind_date - 1}));
                        data.raw.(files{j}).groups{length(data.raw.(files{j}).groups) + 1} = n;
                    end
                end
            end

            setappdata(app.UITable, 'data', data)

            updateTable(app, data)

        end

        % Button pushed function: IdentifyGroupsinObjectsButton
        function IdentifyGroupsinObjectsButtonPushed(app, event)
            
            printLog(app, '005')

            data = getappdata(app.UITable, 'data');

            files = fieldnames(data.raw);
            for i = 1:length(files)
                obj = fieldnames(data.raw.(files{i}));
                obj(strcmp(obj, 'meta')) = [];
                obj(strcmp(obj, 'groups')) = [];
                for j = 1:length(obj)
                    data.raw.(files{i}).(obj{j}).groups = split(obj{j}, '_');
                end
            end

            setappdata(app.UITable, 'data', data)

            updateTable(app, data)

            printLog(app, '006')

        end

        % Button pushed function: RemoveDuplicateswithinRowsButton
        function RemoveDuplicateswithinRowsButtonPushed(app, event)
            
            printLog(app, '003')

            % The data in the table will be used to find the duplicates.
            groups = app.UITable.Data;
            % Pull the current data from the app.
            data = getappdata(app.UITable, 'data');

            % Identify the duplicates within the rows.
            dups = [];
            for i = 1:height(groups)
                % Convert the current table row into cells.
                groupsUnique = table2cell(groups(i,:));
                % Remove empty cells. Empty cells will cause errors.
                groupsUnique(cellfun(@isempty, groupsUnique)) = [];
                % Find the unique groups.
                [~, idxu, idxc] = unique(groupsUnique);
                % Find the index of the unique values that have a count
                % greater than 1.
                ind = sort(idxu(find(histcounts(idxc, 0:max(idxc) + 1) > 1) - 1));
                % Save the indexes of the duplicates.
                dups(i,1:length(ind)) = ind;
            end
            
            % Check that the duplicates are common to every row.
            i = 1;
            while i <= size(dups, 2)
                % If there is more than one unique value in the column than
                % the rows have different values.
                if numel(unique(dups(:,i))) ~= 1
                    % Remove that column as a duplicate.
                    dups(:,i) = [];
                else
                    i = i + 1;
                end
            end

            files = fieldnames(data.raw);
            ind = 1;
            for i = 1:length(files)
                for ii = 1:size(dups, 2)
                    % Find if the current duplicate is at the file or the
                    % object level.
                    if dups(ind, ii) <= length(data.raw.(files{i}).groups)

                        
                        % The -1 is a correction for the file name being
                        % displayed in the table.
                        data.raw.(files{i}).groups(dups(ind, ii) - 1) = [];
                    end
                end
                for ii = 1:size(dups, 2)
                    obj = fieldnames(data.raw.(files{i}));
                    obj(strcmp(obj, 'meta')) = [];
                    obj(strcmp(obj, 'groups')) = [];
                    for k = 1:length(obj)
                        if dups(ind, ii) > length(data.raw.(files{i}).groups) + 1
                            data.raw.(files{i}).(obj{k}).groups(dups(ind, ii)) = [];
                        end
                        ind = ind + 1;
                    end
                end
            end

            setappdata(app.UITable, 'data', data)

            printLog(app, '004')
            
            updateTable(app, data)
            
        end

        % Button pushed function: RemoveDuplicateswithinColumnsButton
        function RemoveDuplicateswithinColumnsButtonPushed(app, event)
            
            printLog(app, '001')

            groups = app.UITable.Data;
            data = getappdata(app.UITable, 'data');

            cols = fieldnames(groups);

            files = fieldnames(data.raw);
            for i = length(cols) - 3 : -1 : 2
                if (sum(cellfun(@isempty, groups.(cols{i}))) == 0 && numel(unique(groups.(cols{i}))) == 1) || sum(cellfun(@isempty, groups.(cols{i}))) == height(groups.(cols{i}))
                    if i < length(data.raw.(files{i}).groups)
                        for j = 1:length(files)
                            data.raw.(files{j}).groups(i - 1) = [];
                        end
                    end
                end
            end

            setappdata(app.UITable, 'data', data)

            printLog(app, '002')
            
            updateTable(app, data)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.GroupingsLabel = uilabel(app.UIFigure);
            app.GroupingsLabel.Text = 'Groupings';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Log = uitextarea(app.UIFigure);
            app.SelectdatetagformatDropDownLabel = uilabel(app.UIFigure);
            app.SelectdatetagformatDropDownLabel.Text = 'Select date tag format';
            app.SelectdatetagformatDropDown = uidropdown(app.UIFigure);
            app.SelectdatetagformatDropDown.Items = {'yyyyMMdd', 'ddMMyyyy'};
            app.SelectdatetagformatDropDown.Editable = 'on';
            app.SelectdatetagformatDropDown.Value = 'yyyyMMdd';
            app.ConvertDatesButton = uibutton(app.UIFigure, 'push');
            app.ConvertDatesButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertDatesButtonPushed, true);
            app.ConvertDatesButton.Text = 'Convert Dates';
            app.SaveResultsandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsandExitSaveResultsandExitButtonPushed, true);
            app.SaveResultsandExitButton.Text = 'Save Results and Exit';
            app.v10Label = uilabel(app.UIFigure);
            app.v10Label.Text = 'v1.0';
            app.IdentifyGroupsinObjectsButton = uibutton(app.UIFigure, 'push');
            app.IdentifyGroupsinObjectsButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyGroupsinObjectsButtonPushed, true);
            app.IdentifyGroupsinObjectsButton.Text = 'Identify Groups in Objects';
            app.RemoveDuplicateswithinRowsButton = uibutton(app.UIFigure, 'push');
            app.RemoveDuplicateswithinRowsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveDuplicateswithinRowsButtonPushed, true);
            app.RemoveDuplicateswithinRowsButton.Text = 'Remove Duplicates within Rows';
            app.RemoveDuplicateswithinColumnsButton = uibutton(app.UIFigure, 'push');
            app.RemoveDuplicateswithinColumnsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveDuplicateswithinColumnsButtonPushed, true);
            app.RemoveDuplicateswithinColumnsButton.Text = 'Remove Duplicates within Columns';
            app.SelectthegroupdatesbelongtoSpinnerLabel = uilabel(app.UIFigure);
            app.SelectthegroupdatesbelongtoSpinnerLabel.Text = 'Select the group dates belong to';
            app.SelectthegroupdatesbelongtoSpinner = uispinner(app.UIFigure);
            app.SelectthegroupdatesbelongtoSpinner.Value = 1;
            app.SelectthegroupwithdatestoconvertSpinnerLabel = uilabel(app.UIFigure);
            app.SelectthegroupwithdatestoconvertSpinnerLabel.Text = 'Select the group with dates to convert';
            app.SelectthegroupwithdatestoconvertSpinner = uispinner(app.UIFigure);
            app.SelectthegroupwithdatestoconvertSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = process_Groupings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end