classdef process_Treatment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SelectStepDropDown_2       matlab.ui.control.DropDown
        SelectStepDropDown_2Label  matlab.ui.control.Label
        RemoveStepButton           matlab.ui.control.Button
        AddStepButton              matlab.ui.control.Button
        SelectStepDropDown         matlab.ui.control.DropDown
        SelectStepDropDownLabel    matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        ProcessButton              matlab.ui.control.Button
        Log                        matlab.ui.control.EditField
        Image                      matlab.ui.control.Image
        DataTreatmentLabel         matlab.ui.control.Label
    end


    properties (Access = private) 
        BAR_App   % Main app object
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function printLog(app, code, newText)
            text = app.Log.Value;
            switch code
                case '001'
                    text{length(text) + 1,1} = [newText{1}];
                case '002'
                    text{length(text) + 1,1} = 'analysis complete';
            end
            app.Log.Value = text;
            scroll(app.Log,'bottom')
            drawnow
        end
    end
    
    methods (Access = public)
        
        function addStep(app, properties)
            steps = app.UITable.Data;

            if isempty(steps)
                steps.Number = num2str(1);
                steps.Treatment{1} = properties.type;
                steps.Parameters{1} = properties;
                app.UITable.Data = struct2table(steps);
                app.UITable.ColumnName = fieldnames(steps);
            else
                steps = [steps;{num2str(height(steps) + 1), properties.type, properties}];
                app.UITable.Data = steps;
            end

            app.SelectStepDropDown_2.Items = join([steps.Number(:), steps.Treatment(:)]);

        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data)
            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;
            
            setappdata(app.UITable, 'data', data)
            
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Callback function
        function SaveResultsandExitButtonPushed(app, event)
            data = getappdata(app.UITable, 'data');
            analysis = app.DataTreatmentLabel.Text;
            
            analysisComplete(app.BAR_App, data, analysis)

            delete(app)
        end

        % Button pushed function: AddStepButton
        function AddStepButtonPushed(app, event)
            
            switch app.SelectStepDropDown.Value
                case 'Low-pass Filter'
                    process_Treatment_LowPass(app, 30)
            end
        end

        % Button pushed function: RemoveStepButton
        function RemoveStepButtonPushed(app, event)
            
                steps = app.UITable.Data;

                step2remove = app.SelectStepDropDown_2.Value;
                
                ind = strfind(step2remove, ' ');
                n = str2double(step2remove(1:ind(1) - 1));

                steps(n,:) = [];
                steps.Number = cellstr(num2str([1:height(steps)]'));

                app.UITable.Data = steps;
                app.SelectStepDropDown_2.Items = join([steps.Number(:), steps.Treatment(:)]);

        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.DataTreatmentLabel = uilabel(app.UIFigure);
            app.DataTreatmentLabel.Text = 'Data Treatment';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.Log = uieditfield(app.UIFigure, 'text');
            app.Log.Editable = 'off';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SelectStepDropDownLabel = uilabel(app.UIFigure);
            app.SelectStepDropDownLabel.Text = 'Select Step';
            app.SelectStepDropDown = uidropdown(app.UIFigure);
            app.SelectStepDropDown.Items = {'Low-pass Filter'};
            app.SelectStepDropDown.Value = 'Low-pass Filter';
            app.AddStepButton = uibutton(app.UIFigure, 'push');
            app.AddStepButton.ButtonPushedFcn = createCallbackFcn(app, @AddStepButtonPushed, true);
            app.AddStepButton.Text = 'Add Step';
            app.RemoveStepButton = uibutton(app.UIFigure, 'push');
            app.RemoveStepButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveStepButtonPushed, true);
            app.RemoveStepButton.Text = 'Remove Step';
            app.SelectStepDropDown_2Label = uilabel(app.UIFigure);
            app.SelectStepDropDown_2Label.Text = 'Select Step';
            app.SelectStepDropDown_2 = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = process_Treatment(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end